classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EXPORTDATAButton        matlab.ui.control.Button
        MVCNORMALISATIONButton  matlab.ui.control.Button
        PROCESSINGTRACKBButton  matlab.ui.control.Button
        PROCESSINGTRACKAButton  matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        RAWDATATab              matlab.ui.container.Tab
        TabGroup2               matlab.ui.container.TabGroup
        CH1Tab                  matlab.ui.container.Tab
        RawDataChannel1         matlab.ui.control.UIAxes
        CH2Tab                  matlab.ui.container.Tab
        RawDataChannel2         matlab.ui.control.UIAxes
        CH3Tab                  matlab.ui.container.Tab
        RawDataChannel3         matlab.ui.control.UIAxes
        CH4Tab                  matlab.ui.container.Tab
        RawDataChannel4         matlab.ui.control.UIAxes
        CH5Tab                  matlab.ui.container.Tab
        RawDataChannel5         matlab.ui.control.UIAxes
        mVDATATab               matlab.ui.container.Tab
        TabGroup2_2             matlab.ui.container.TabGroup
        CH1Tab_2                matlab.ui.container.Tab
        MvDataChannel1          matlab.ui.control.UIAxes
        CH2Tab_2                matlab.ui.container.Tab
        MvDataChannel2          matlab.ui.control.UIAxes
        CH3Tab_2                matlab.ui.container.Tab
        MvDataChannel3          matlab.ui.control.UIAxes
        CH4Tab_2                matlab.ui.container.Tab
        MvDataChannel4          matlab.ui.control.UIAxes
        CH5Tab_2                matlab.ui.container.Tab
        MvDataChannel5          matlab.ui.control.UIAxes
        FFTANALYSISTab          matlab.ui.container.Tab
        TabGroup2_3             matlab.ui.container.TabGroup
        CH1Tab_3                matlab.ui.container.Tab
        FFTDataChannel1         matlab.ui.control.UIAxes
        CH2Tab_3                matlab.ui.container.Tab
        FFTDataChannel2         matlab.ui.control.UIAxes
        CH3Tab_3                matlab.ui.container.Tab
        FFTDataChannel3         matlab.ui.control.UIAxes
        CH4Tab_3                matlab.ui.container.Tab
        FFTDataChannel4         matlab.ui.control.UIAxes
        CH5Tab_3                matlab.ui.container.Tab
        FFTDataChannel5         matlab.ui.control.UIAxes
        REMOVEOUTLIERTab        matlab.ui.container.Tab
        TabGroup2_4             matlab.ui.container.TabGroup
        CH1Tab_4                matlab.ui.container.Tab
        OutlierDataChannel1     matlab.ui.control.UIAxes
        CH2Tab_4                matlab.ui.container.Tab
        OutlierDataChannel2     matlab.ui.control.UIAxes
        CH3Tab_4                matlab.ui.container.Tab
        OutlierDataChannel3     matlab.ui.control.UIAxes
        CH4Tab_4                matlab.ui.container.Tab
        OutlierDataChannel4     matlab.ui.control.UIAxes
        CH5Tab_4                matlab.ui.container.Tab
        OutlierDataChannel5     matlab.ui.control.UIAxes
        PROCESSINGTRACKATab     matlab.ui.container.Tab
        PROCESSINGTRACKBTab     matlab.ui.container.Tab
        MVCNORMALISATIONTab     matlab.ui.container.Tab
        OutlierOptionDropDown   matlab.ui.control.DropDown
        REMOVEOUTLIERButton     matlab.ui.control.Button
        FFTButton               matlab.ui.control.Button
        TOmVButton              matlab.ui.control.Button
        mVDATALabel             matlab.ui.control.Label
        TEXTFILEDATALabel       matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        UITable_2               matlab.ui.control.Table
        UITable                 matlab.ui.control.Table
        READTXTFILEButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        mVDataMatrix % Description
        nSeq % Description
        FFTDataMatrix % Description
        dropDownVal % Description
        rawChannelData % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

%READ TEXT FILE KNOP
%KIES EEN FILE IN DATA/PP01 FOLDER
%TOON FILE TITEL IN EDIT FIELD
%TOON FILE CONTENT IN TEXTAREA

[filename,path]=uigetfile("DATA/PP01/*.txt");
app.FilenameEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
%app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
app.rawChannelData = loadedData(:,3:end);
app.nSeq = loadedData(:,1);

channel1RawData = app.rawChannelData(:,1);
channel2RawData = app.rawChannelData(:,2);
channel3RawData = app.rawChannelData(:,3);
channel4RawData = app.rawChannelData(:,4);
channel5RawData = app.rawChannelData(:,5);

plot(app.RawDataChannel1,app.nSeq,channel1RawData);
plot(app.RawDataChannel2,app.nSeq,channel2RawData);
plot(app.RawDataChannel3,app.nSeq,channel3RawData);
plot(app.RawDataChannel4,app.nSeq,channel4RawData);
plot(app.RawDataChannel5,app.nSeq,channel5RawData);


app.TOmVButton.Enable = 'on';
app.mVDataMatrix = ((app.rawChannelData ./ (2^16)) - (0.5)) * 3;


%STAP 3
% Fs = 1000;            % Sampling frequency                    
% T = 1/Fs;             % Sampling period       
% L = 1500;             % Length of signal
% t = (0:L-1)*T;        % Time vector
% 
% S = 0.7*sin(2*pi*50*t) + sin(2*pi*120*t);
% 
% X = S + 2*randn(size(t));
% 
% plot(1000*t(1:50),X(1:50))
% title('Signal Corrupted with Zero-Mean Random Noise')
% xlabel('t (milliseconds)')
% ylabel('X(t)')
% 
% Y = fft(X);
% 
% P2 = abs(Y/L);
% P1 = P2(1:L/2+1);
% P1(2:end-1) = 2*P1(2:end-1);

app.FFTDataMatrix = fft(app.mVDataMatrix);


% tiledlayout(5,1);
% Outlier1 = rmoutliers(B,"mean","ThresholdFactor",1);
% Outlier2 = rmoutliers(B,"mean","ThresholdFactor",2);
% Outlier3 = rmoutliers(B,"mean","ThresholdFactor",3);
% 
% 
% nexttile;
% plot(B);
% title('mV data');
% nexttile;
% plot(C);
% title('FFT data');
% nexttile;
% plot(Outlier1);
% title('Outlier1 data');
% nexttile;
% plot(Outlier2);
% title('Outlier2 data');
% nexttile;
% plot(Outlier3);
% title('Outlier3 data');

        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end

        % Button pushed function: TOmVButton
        function TOmVButtonPushed(app, event)
                
            app.UITable_2.Data = app.mVDataMatrix;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            plot(app.MvDataChannel1,app.nSeq,channel1MvData);
            plot(app.MvDataChannel2,app.nSeq,channel2MvData);
            plot(app.MvDataChannel3,app.nSeq,channel3MvData);
            plot(app.MvDataChannel4,app.nSeq,channel4MvData);
            plot(app.MvDataChannel5,app.nSeq,channel5MvData);

            app.FFTButton.Enable = 'on';

        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            channel1FFTData = app.FFTDataMatrix(:,1);
            channel2FFTData = app.FFTDataMatrix(:,2);
            channel3FFTData = app.FFTDataMatrix(:,3);
            channel4FFTData = app.FFTDataMatrix(:,4);
            channel5FFTData = app.FFTDataMatrix(:,5);

            plot(app.FFTDataChannel1,app.nSeq,channel1FFTData);
            plot(app.FFTDataChannel2,app.nSeq,channel2FFTData);
            plot(app.FFTDataChannel3,app.nSeq,channel3FFTData);
            plot(app.FFTDataChannel4,app.nSeq,channel4FFTData);
            plot(app.FFTDataChannel5,app.nSeq,channel5FFTData);

            app.OutlierOptionDropDown.Enable = 'on';
            app.REMOVEOUTLIERButton.Enable = 'on';

        end

        % Button pushed function: REMOVEOUTLIERButton
        function REMOVEOUTLIERButtonPushed(app, event)

            Outlier1Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",1);
            Outlier1Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",1);
            Outlier1Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",1);
            Outlier1Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",1);
            Outlier1Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",1);

            Outlier2Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",2);
            Outlier2Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",2);
            Outlier2Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",2);
            Outlier2Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",2);
            Outlier2Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",2);

            Outlier3Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",3);
            Outlier3Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",3);
            Outlier3Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",3);
            Outlier3Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",3);
            Outlier3Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",3);
            
            ctr1 = (1:1:size(Outlier1Channel1,1));
            ctr2 = (1:1:size(Outlier1Channel2,1));
            ctr3 = (1:1:size(Outlier1Channel3,1));
            ctr4 = (1:1:size(Outlier1Channel4,1));
            ctr5 = (1:1:size(Outlier1Channel5,1));
            
            ctr6 = (1:1:size(Outlier2Channel1,1));
            ctr7 = (1:1:size(Outlier2Channel2,1));
            ctr8 = (1:1:size(Outlier2Channel3,1));
            ctr9 = (1:1:size(Outlier2Channel4,1));
            ctr10 = (1:1:size(Outlier2Channel5,1));
            
            ctr11 = (1:1:size(Outlier3Channel1,1));
            ctr12 = (1:1:size(Outlier3Channel2,1));
            ctr13 = (1:1:size(Outlier3Channel3,1));
            ctr14 = (1:1:size(Outlier3Channel4,1));
            ctr15 = (1:1:size(Outlier3Channel5,1));

            disp(app.dropDownVal);

            if app.OutlierOptionDropDown.Value == "1"                 
                 plot(app.OutlierDataChannel1,ctr1,Outlier1Channel1);
                 plot(app.OutlierDataChannel2,ctr2,Outlier1Channel2);
                 plot(app.OutlierDataChannel3,ctr3,Outlier1Channel3);
                 plot(app.OutlierDataChannel4,ctr4,Outlier1Channel4);
                 plot(app.OutlierDataChannel5,ctr5,Outlier1Channel5);
            elseif app.OutlierOptionDropDown.Value == "2"                 
                 plot(app.OutlierDataChannel1,ctr6,Outlier2Channel1);
                 plot(app.OutlierDataChannel2,ctr7,Outlier2Channel2);
                 plot(app.OutlierDataChannel3,ctr8,Outlier2Channel3);
                 plot(app.OutlierDataChannel4,ctr9,Outlier2Channel4);
                 plot(app.OutlierDataChannel5,ctr10,Outlier2Channel5);
            elseif app.OutlierOptionDropDown.Value == "3"                     
                 plot(app.OutlierDataChannel1,ctr11,Outlier3Channel1);
                 plot(app.OutlierDataChannel2,ctr12,Outlier3Channel2);
                 plot(app.OutlierDataChannel3,ctr13,Outlier3Channel3);
                 plot(app.OutlierDataChannel4,ctr14,Outlier3Channel4);
                 plot(app.OutlierDataChannel5,ctr15,Outlier3Channel5);
            else
                disp("value not found...");
            end


        end

        % Value changed function: OutlierOptionDropDown
        function OutlierOptionDropDownValueChanged(app, event)
            app.dropDownVal = app.OutlierOptionDropDown.Value;
        end

        % Button pushed function: PROCESSINGTRACKBButton
        function PROCESSINGTRACKBButtonPushed(app, event)
            Fs = 100;
            Framelength = 10;
            ctr1 = (1:1:size(app.rawChannelData,1));
            ch1 = app.rawChannelData(:,1); 
            movrmsWin = dsp.MovingRMS(20);
            movrmsWin_overlap = dsp.MovingRMS(20,15);
            movrmsExp = dsp.MovingRMS('Method','Exponential weighting',...
            'ForgettingFactor',0.995);

            scope  = timescope('SampleRate',[Fs,Fs,Fs/(20-15),Fs],...
                'TimeSpanOverrunAction','Scroll',...
                'TimeSpanSource','Property',...
                'TimeSpan',500,...
                'ShowGrid',true,...
                'YLimits',[-10.0 10]);
            title = 'Moving RMS';
            scope.Title = title;

                for i = 1:size(ch1,1)
                    x = 1 + 0.1 * randn(Framelength,1);
                    y1 = movrmsWin(ch1(i,1));
                    y2 = movrmsWin_overlap(ch1(i,1));
                    y3 = movrmsExp(ch1(i,1));
                    scope(x,y1,y2,y3);
                end
                show(scope);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.TEXTFILEDATALabel = uilabel(app.UIFigure);
            app.TEXTFILEDATALabel.Text = 'TEXT  FILE DATA';
            app.mVDATALabel = uilabel(app.UIFigure);
            app.mVDATALabel.Text = 'mV DATA';
            app.TOmVButton = uibutton(app.UIFigure, 'push');
            app.TOmVButton.ButtonPushedFcn = createCallbackFcn(app, @TOmVButtonPushed, true);
            app.TOmVButton.Enable = 'off';
            app.TOmVButton.Text = 'TO mV';
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERButton = uibutton(app.UIFigure, 'push');
            app.REMOVEOUTLIERButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEOUTLIERButtonPushed, true);
            app.REMOVEOUTLIERButton.Enable = 'off';
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.OutlierOptionDropDown = uidropdown(app.UIFigure);
            app.OutlierOptionDropDown.Items = {'1', '2', '3'};
            app.OutlierOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierOptionDropDownValueChanged, true);
            app.OutlierOptionDropDown.Enable = 'off';
            app.OutlierOptionDropDown.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.TabGroup2 = uitabgroup(app.RAWDATATab);
            app.CH1Tab = uitab(app.TabGroup2);
            app.CH1Tab.Title = 'CH1';
            app.RawDataChannel1 = uiaxes(app.CH1Tab);
            title(app.RawDataChannel1, 'CHANNEL 1')
            xlabel(app.RawDataChannel1, 'X')
            ylabel(app.RawDataChannel1, 'Y')
            zlabel(app.RawDataChannel1, 'Z')
            app.CH2Tab = uitab(app.TabGroup2);
            app.CH2Tab.Title = 'CH2';
            app.RawDataChannel2 = uiaxes(app.CH2Tab);
            title(app.RawDataChannel2, 'CHANNEL 2')
            xlabel(app.RawDataChannel2, 'X')
            ylabel(app.RawDataChannel2, 'Y')
            zlabel(app.RawDataChannel2, 'Z')
            app.CH3Tab = uitab(app.TabGroup2);
            app.CH3Tab.Title = 'CH3';
            app.RawDataChannel3 = uiaxes(app.CH3Tab);
            title(app.RawDataChannel3, 'CHANNEL 3')
            xlabel(app.RawDataChannel3, 'X')
            ylabel(app.RawDataChannel3, 'Y')
            zlabel(app.RawDataChannel3, 'Z')
            app.CH4Tab = uitab(app.TabGroup2);
            app.CH4Tab.Title = 'CH4';
            app.RawDataChannel4 = uiaxes(app.CH4Tab);
            title(app.RawDataChannel4, 'CHANNEL 4')
            xlabel(app.RawDataChannel4, 'X')
            ylabel(app.RawDataChannel4, 'Y')
            zlabel(app.RawDataChannel4, 'Z')
            app.CH5Tab = uitab(app.TabGroup2);
            app.CH5Tab.Title = 'CH5';
            app.RawDataChannel5 = uiaxes(app.CH5Tab);
            title(app.RawDataChannel5, 'CHANNEL 5')
            xlabel(app.RawDataChannel5, 'X')
            ylabel(app.RawDataChannel5, 'Y')
            zlabel(app.RawDataChannel5, 'Z')
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.TabGroup2_2 = uitabgroup(app.mVDATATab);
            app.CH1Tab_2 = uitab(app.TabGroup2_2);
            app.CH1Tab_2.Title = 'CH1';
            app.MvDataChannel1 = uiaxes(app.CH1Tab_2);
            title(app.MvDataChannel1, 'Title')
            xlabel(app.MvDataChannel1, 'X')
            ylabel(app.MvDataChannel1, 'Y')
            zlabel(app.MvDataChannel1, 'Z')
            app.CH2Tab_2 = uitab(app.TabGroup2_2);
            app.CH2Tab_2.Title = 'CH2';
            app.MvDataChannel2 = uiaxes(app.CH2Tab_2);
            title(app.MvDataChannel2, 'Title')
            xlabel(app.MvDataChannel2, 'X')
            ylabel(app.MvDataChannel2, 'Y')
            zlabel(app.MvDataChannel2, 'Z')
            app.CH3Tab_2 = uitab(app.TabGroup2_2);
            app.CH3Tab_2.Title = 'CH3';
            app.MvDataChannel3 = uiaxes(app.CH3Tab_2);
            title(app.MvDataChannel3, 'Title')
            xlabel(app.MvDataChannel3, 'X')
            ylabel(app.MvDataChannel3, 'Y')
            zlabel(app.MvDataChannel3, 'Z')
            app.CH4Tab_2 = uitab(app.TabGroup2_2);
            app.CH4Tab_2.Title = 'CH4';
            app.MvDataChannel4 = uiaxes(app.CH4Tab_2);
            title(app.MvDataChannel4, 'Title')
            xlabel(app.MvDataChannel4, 'X')
            ylabel(app.MvDataChannel4, 'Y')
            zlabel(app.MvDataChannel4, 'Z')
            app.CH5Tab_2 = uitab(app.TabGroup2_2);
            app.CH5Tab_2.Title = 'CH5';
            app.MvDataChannel5 = uiaxes(app.CH5Tab_2);
            title(app.MvDataChannel5, 'Title')
            xlabel(app.MvDataChannel5, 'X')
            ylabel(app.MvDataChannel5, 'Y')
            zlabel(app.MvDataChannel5, 'Z')
            app.FFTANALYSISTab = uitab(app.TabGroup);
            app.FFTANALYSISTab.Title = 'FFT ANALYSIS';
            app.TabGroup2_3 = uitabgroup(app.FFTANALYSISTab);
            app.CH1Tab_3 = uitab(app.TabGroup2_3);
            app.CH1Tab_3.Title = 'CH1';
            app.FFTDataChannel1 = uiaxes(app.CH1Tab_3);
            title(app.FFTDataChannel1, 'Title')
            xlabel(app.FFTDataChannel1, 'X')
            ylabel(app.FFTDataChannel1, 'Y')
            zlabel(app.FFTDataChannel1, 'Z')
            app.CH2Tab_3 = uitab(app.TabGroup2_3);
            app.CH2Tab_3.Title = 'CH2';
            app.FFTDataChannel2 = uiaxes(app.CH2Tab_3);
            title(app.FFTDataChannel2, 'Title')
            xlabel(app.FFTDataChannel2, 'X')
            ylabel(app.FFTDataChannel2, 'Y')
            zlabel(app.FFTDataChannel2, 'Z')
            app.CH3Tab_3 = uitab(app.TabGroup2_3);
            app.CH3Tab_3.Title = 'CH3';
            app.FFTDataChannel3 = uiaxes(app.CH3Tab_3);
            title(app.FFTDataChannel3, 'Title')
            xlabel(app.FFTDataChannel3, 'X')
            ylabel(app.FFTDataChannel3, 'Y')
            zlabel(app.FFTDataChannel3, 'Z')
            app.CH4Tab_3 = uitab(app.TabGroup2_3);
            app.CH4Tab_3.Title = 'CH4';
            app.FFTDataChannel4 = uiaxes(app.CH4Tab_3);
            title(app.FFTDataChannel4, 'Title')
            xlabel(app.FFTDataChannel4, 'X')
            ylabel(app.FFTDataChannel4, 'Y')
            zlabel(app.FFTDataChannel4, 'Z')
            app.CH5Tab_3 = uitab(app.TabGroup2_3);
            app.CH5Tab_3.Title = 'CH5';
            app.FFTDataChannel5 = uiaxes(app.CH5Tab_3);
            title(app.FFTDataChannel5, 'Title')
            xlabel(app.FFTDataChannel5, 'X')
            ylabel(app.FFTDataChannel5, 'Y')
            zlabel(app.FFTDataChannel5, 'Z')
            app.REMOVEOUTLIERTab = uitab(app.TabGroup);
            app.REMOVEOUTLIERTab.Title = 'REMOVE OUTLIER';
            app.TabGroup2_4 = uitabgroup(app.REMOVEOUTLIERTab);
            app.CH1Tab_4 = uitab(app.TabGroup2_4);
            app.CH1Tab_4.Title = 'CH1';
            app.OutlierDataChannel1 = uiaxes(app.CH1Tab_4);
            title(app.OutlierDataChannel1, 'Title')
            xlabel(app.OutlierDataChannel1, 'X')
            ylabel(app.OutlierDataChannel1, 'Y')
            zlabel(app.OutlierDataChannel1, 'Z')
            app.CH2Tab_4 = uitab(app.TabGroup2_4);
            app.CH2Tab_4.Title = 'CH2';
            app.OutlierDataChannel2 = uiaxes(app.CH2Tab_4);
            title(app.OutlierDataChannel2, 'Title')
            xlabel(app.OutlierDataChannel2, 'X')
            ylabel(app.OutlierDataChannel2, 'Y')
            zlabel(app.OutlierDataChannel2, 'Z')
            app.CH3Tab_4 = uitab(app.TabGroup2_4);
            app.CH3Tab_4.Title = 'CH3';
            app.OutlierDataChannel3 = uiaxes(app.CH3Tab_4);
            title(app.OutlierDataChannel3, 'Title')
            xlabel(app.OutlierDataChannel3, 'X')
            ylabel(app.OutlierDataChannel3, 'Y')
            zlabel(app.OutlierDataChannel3, 'Z')
            app.CH4Tab_4 = uitab(app.TabGroup2_4);
            app.CH4Tab_4.Title = 'CH4';
            app.OutlierDataChannel4 = uiaxes(app.CH4Tab_4);
            title(app.OutlierDataChannel4, 'Title')
            xlabel(app.OutlierDataChannel4, 'X')
            ylabel(app.OutlierDataChannel4, 'Y')
            zlabel(app.OutlierDataChannel4, 'Z')
            app.CH5Tab_4 = uitab(app.TabGroup2_4);
            app.CH5Tab_4.Title = 'CH5';
            app.OutlierDataChannel5 = uiaxes(app.CH5Tab_4);
            title(app.OutlierDataChannel5, 'Title')
            xlabel(app.OutlierDataChannel5, 'X')
            ylabel(app.OutlierDataChannel5, 'Y')
            zlabel(app.OutlierDataChannel5, 'Z')
            app.PROCESSINGTRACKATab = uitab(app.TabGroup);
            app.PROCESSINGTRACKATab.Title = 'PROCESSING TRACK A';
            app.PROCESSINGTRACKBTab = uitab(app.TabGroup);
            app.PROCESSINGTRACKBTab.Title = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONTab = uitab(app.TabGroup);
            app.MVCNORMALISATIONTab.Title = 'MVC NORMALISATION';
            app.PROCESSINGTRACKAButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKAButton.Enable = 'off';
            app.PROCESSINGTRACKAButton.Text = 'PROCESSING TRACK A';
            app.PROCESSINGTRACKBButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKBButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKBButtonPushed, true);
            app.PROCESSINGTRACKBButton.Text = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONButton = uibutton(app.UIFigure, 'push');
            app.MVCNORMALISATIONButton.Enable = 'off';
            app.MVCNORMALISATIONButton.Text = 'MVC NORMALISATION';
            app.EXPORTDATAButton = uibutton(app.UIFigure, 'push');
            app.EXPORTDATAButton.Enable = 'off';
            app.EXPORTDATAButton.Text = 'EXPORT DATA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end